classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        BasicFeaturesTab        matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        LoadECGSignalButton     matlab.ui.control.Button
        FindPQRButton           matlab.ui.control.Button
        MICAProjectLabel        matlab.ui.control.Label
        TimeWindowPanel         matlab.ui.container.Panel
        window_beg              matlab.ui.control.NumericEditField
        toLabel                 matlab.ui.control.Label
        window_end              matlab.ui.control.NumericEditField
        ApplyButton             matlab.ui.control.Button
        BradyTachycardiaButton  matlab.ui.control.Button
        BPMLabel                matlab.ui.control.Label
        EctopicbeatButton       matlab.ui.control.Button
        FibrilationsButton      matlab.ui.control.Button
        ResultLabel             matlab.ui.control.Label
        BPMLabel_2              matlab.ui.control.Label
        InverseECGSignalButton  matlab.ui.control.Button
        AdvancedFeaturesTab     matlab.ui.container.Tab
    end


    properties (Access = public)
        
        data; % ECG Signal
        data2; % ECG Signal after computing
        time_axis % Data length
        Fs % Sampling frequency
        R % R-points position
        Q % Q-points position
        S % S-points position
    end

    methods (Access = private)
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
        end

        % Button pushed function: BradyTachycardiaButton
        function B_T(app, event)
            [result, bpm] = Brady_Tachy(app.R, app.Fs);
            
            % Display Result 
            app.ResultLabel.Text = result;
            app.ResultLabel.Visible = 'on';
            
            % Display BPM
            app.BPMLabel.Text = int2str(round(bpm));
            app.BPMLabel.Visible = 'on';
            app.BPMLabel_2.Visible = 'on';
        end

        % Button pushed function: LoadECGSignalButton
        function LoadECGSignalButtonPushed(app, event)
            % Load a file
            [file,path] = uigetfile('*.mat', 'rt');
            signal = load(fullfile(path, file));
            
            % ecg data
            app.data = signal.ecg;
            
            % Sampling frequency
            app.Fs = signal.Fs;
            N = size(app.data,2);
            
            % Data length
            app.time_axis = (1:N)/app.Fs;
            
            % Plot ECG Signal
            plot(app.UIAxes, app.time_axis, app.data);
            
            % Actualize Window value
            app.window_beg.Value = 0;
            app.window_end.Value = app.time_axis(end);
            change_window(app,event);
            
            % Reset labels
            app.BPMLabel.Visible = 'off';
            app.ResultLabel.Visible = 'off';
           
        end

        % Button pushed function: ApplyButton
        function change_window(app, event)
            value1 = app.window_beg.Value;
            value2 = app.window_end.Value;
            if (value2>value1)
                app.UIAxes.XLim = [value1, value2];
                app.window_end.BackgroundColor = [1.00, 1.00, 1.00];
            else
                app.window_end.BackgroundColor = [0.95, 0.38, 0.38];
            end
        end

        % Button pushed function: EctopicbeatButton
        function ectopic(app, event)
            result = Ectopic_beat(app.R, app.Fs);
            if ~isempty(result)
                app.ResultLabel.Text = 'Yes, represented with crosses';
                app.ResultLabel.Visible = 'on';
                plot(app.UIAxes, app.time_axis, app.data2);
                app.UIAxes.NextPlot = 'add';
                plot(app.UIAxes, result/app.Fs, app.data2(result), 'x');
                app.UIAxes.NextPlot = 'replacechildren';
            else
                app.ResultLabel.Text = 'No';
                app.ResultLabel.Visible = 'on';
            end
        end

        % Button pushed function: FindPQRButton
        function findPQR(app, event)
            
            % Algorythm
            [app.data2, app.R, app.Q, app.S]= Pan_and_Tompkins(app.data,app.Fs);
            
            % Display
            plot(app.UIAxes, app.time_axis, app.data2);
            app.UIAxes.NextPlot = 'add';
            plot(app.UIAxes, app.R./app.Fs, app.data2(app.R),'o');
            plot(app.UIAxes, app.Q./app.Fs, app.data2(app.Q),'o');
            plot(app.UIAxes, app.S./app.Fs, app.data2(app.S),'o');
            app.UIAxes.NextPlot = 'replacechildren';
        end

        % Callback function
        function time_axis_beg(app, event)
            
        end

        % Button pushed function: InverseECGSignalButton
        function inverse(app, event)
            app.data=-app.data;
            
            % Plot ECG Signal
            plot(app.UIAxes, app.time_axis, app.data);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicFeaturesTab = uitab(app.TabGroup);
            app.BasicFeaturesTab.Title = 'Basic Features';
            app.UIAxes = uiaxes(app.BasicFeaturesTab);
            title(app.UIAxes, 'ECG Signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Magnitude')
            app.Label = uilabel(app.BasicFeaturesTab);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.LoadECGSignalButton = uibutton(app.BasicFeaturesTab, 'push');
            app.LoadECGSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadECGSignalButtonPushed, true);
            app.LoadECGSignalButton.Text = 'Load ECG Signal';
            app.FindPQRButton = uibutton(app.BasicFeaturesTab, 'push');
            app.FindPQRButton.ButtonPushedFcn = createCallbackFcn(app, @findPQR, true);
            app.FindPQRButton.Text = 'Find PQR';
            app.MICAProjectLabel = uilabel(app.BasicFeaturesTab);
            app.MICAProjectLabel.Text = 'MICA Project';
            app.TimeWindowPanel = uipanel(app.BasicFeaturesTab);
            app.TimeWindowPanel.Title = 'Time Window';
            app.window_beg = uieditfield(app.TimeWindowPanel, 'numeric');
            app.window_beg.Limits = [0 Inf];
            app.toLabel = uilabel(app.TimeWindowPanel);
            app.toLabel.Text = 'to';
            app.window_end = uieditfield(app.TimeWindowPanel, 'numeric');
            app.ApplyButton = uibutton(app.TimeWindowPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @change_window, true);
            app.ApplyButton.Text = 'Apply';
            app.BradyTachycardiaButton = uibutton(app.BasicFeaturesTab, 'push');
            app.BradyTachycardiaButton.ButtonPushedFcn = createCallbackFcn(app, @B_T, true);
            app.BradyTachycardiaButton.Text = 'Brady/Tachycardia';
            app.BPMLabel = uilabel(app.BasicFeaturesTab);
            app.BPMLabel.Visible = 'off';
            app.BPMLabel.Text = 'BPM';
            app.EctopicbeatButton = uibutton(app.BasicFeaturesTab, 'push');
            app.EctopicbeatButton.ButtonPushedFcn = createCallbackFcn(app, @ectopic, true);
            app.EctopicbeatButton.Text = 'Ectopic beat';
            app.FibrilationsButton = uibutton(app.BasicFeaturesTab, 'push');
            app.FibrilationsButton.Text = 'Fibrilations';
            app.ResultLabel = uilabel(app.BasicFeaturesTab);
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'Result';
            app.BPMLabel_2 = uilabel(app.BasicFeaturesTab);
            app.BPMLabel_2.Visible = 'off';
            app.BPMLabel_2.Text = 'BPM';
            app.InverseECGSignalButton = uibutton(app.BasicFeaturesTab, 'push');
            app.InverseECGSignalButton.ButtonPushedFcn = createCallbackFcn(app, @inverse, true);
            app.InverseECGSignalButton.Text = 'Inverse ECG Signal';
            app.AdvancedFeaturesTab = uitab(app.TabGroup);
            app.AdvancedFeaturesTab.Title = 'Advanced Features';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end